classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        ContrastEnhancementTab      matlab.ui.container.Tab
        OutputImageLabel            matlab.ui.control.Label
        InputImageLabel             matlab.ui.control.Label
        SelectImageDropDown         matlab.ui.control.DropDown
        SelectImageDropDownLabel    matlab.ui.control.Label
        UIAxes2_6                   matlab.ui.control.UIAxes
        UIAxes2_5                   matlab.ui.control.UIAxes
        UIAxes2_4                   matlab.ui.control.UIAxes
        UIAxes2_3                   matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes2_2                   matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
        HistogramEqualizationTab    matlab.ui.container.Tab
        LoadImageButton             matlab.ui.control.Button
        SelectImageDropDown_2       matlab.ui.control.DropDown
        SelectImageDropDown_2Label  matlab.ui.control.Label
        OutputImageLabel_2          matlab.ui.control.Label
        InputImageLabel_2           matlab.ui.control.Label
        UIAxes2_12                  matlab.ui.control.UIAxes
        UIAxes2_11                  matlab.ui.control.UIAxes
        UIAxes2_10                  matlab.ui.control.UIAxes
        UIAxes2_9                   matlab.ui.control.UIAxes
        UIAxes_4                    matlab.ui.control.UIAxes
        UIAxes_3                    matlab.ui.control.UIAxes
        UIAxes2_8                   matlab.ui.control.UIAxes
        UIAxes2_7                   matlab.ui.control.UIAxes
        HistogramSpecificationTab   matlab.ui.container.Tab
        TargetImageDropDown_2       matlab.ui.control.DropDown
        TargetImageDropDown_2Label  matlab.ui.control.Label
        SelectImageDropDown_3       matlab.ui.control.DropDown
        SelectImageDropDown_3Label  matlab.ui.control.Label
        OutputImageLabel_3          matlab.ui.control.Label
        InputImageLabel_3           matlab.ui.control.Label
        UIAxes2_18                  matlab.ui.control.UIAxes
        UIAxes2_17                  matlab.ui.control.UIAxes
        UIAxes2_16                  matlab.ui.control.UIAxes
        UIAxes2_15                  matlab.ui.control.UIAxes
        UIAxes_6                    matlab.ui.control.UIAxes
        UIAxes_5                    matlab.ui.control.UIAxes
        UIAxes2_14                  matlab.ui.control.UIAxes
        UIAxes2_13                  matlab.ui.control.UIAxes
        UIAxes19                    matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function updateimage(app, fname)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Contrast Enhancement

            %% Histogram Sepcification
            
            % Configure image axes
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_3.Colormap = gray(256);
            axis(app.UIAxes_3, 'image');
            
            % Update the image and histograms
            updateimage(app, '../images/gorilla.tif');

            %% Histogram Specification
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               fname = [p f];
               updateimage(app, fname);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes19 = uiaxes(app.UIFigure);
            title(app.UIAxes19, 'Title')
            xlabel(app.UIAxes19, 'X')
            ylabel(app.UIAxes19, 'Y')
            zlabel(app.UIAxes19, 'Z')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ContrastEnhancementTab = uitab(app.TabGroup);
            app.ContrastEnhancementTab.Title = 'Contrast Enhancement';
            app.UIAxes2 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2, 'Red')
            xlabel(app.UIAxes2, 'Intensity')
            ylabel(app.UIAxes2, 'Pixels')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 128 255];
            app.UIAxes2.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_2 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2_2, 'Green')
            xlabel(app.UIAxes2_2, 'Intensity')
            ylabel(app.UIAxes2_2, 'Pixels')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [0 0.5 1];
            app.UIAxes2_2.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes = uiaxes(app.ContrastEnhancementTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes_2 = uiaxes(app.ContrastEnhancementTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2_3, 'Blue')
            xlabel(app.UIAxes2_3, 'Intensity')
            ylabel(app.UIAxes2_3, 'Pixels')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [0 0.5 1];
            app.UIAxes2_3.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_4 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2_4, 'Red')
            xlabel(app.UIAxes2_4, 'Intensity')
            ylabel(app.UIAxes2_4, 'Pixels')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XTick = [0 128 255];
            app.UIAxes2_4.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_5 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2_5, 'Green')
            xlabel(app.UIAxes2_5, 'Intensity')
            ylabel(app.UIAxes2_5, 'Pixels')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_5.XTick = [0 0.5 1];
            app.UIAxes2_5.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_6 = uiaxes(app.ContrastEnhancementTab);
            title(app.UIAxes2_6, 'Blue')
            xlabel(app.UIAxes2_6, 'Intensity')
            ylabel(app.UIAxes2_6, 'Pixels')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XTick = [0 0.5 1];
            app.UIAxes2_6.XTickLabel = {'0'; '128'; '255'};
            app.SelectImageDropDownLabel = uilabel(app.ContrastEnhancementTab);
            app.SelectImageDropDownLabel.Text = 'Select Image';
            app.SelectImageDropDown = uidropdown(app.ContrastEnhancementTab);
            app.InputImageLabel = uilabel(app.ContrastEnhancementTab);
            app.InputImageLabel.Text = 'Input Image';
            app.OutputImageLabel = uilabel(app.ContrastEnhancementTab);
            app.OutputImageLabel.Text = 'Output Image';
            app.HistogramEqualizationTab = uitab(app.TabGroup);
            app.HistogramEqualizationTab.Title = 'Histogram Equalization';
            app.UIAxes2_7 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_7, 'Red')
            xlabel(app.UIAxes2_7, 'Intensity')
            ylabel(app.UIAxes2_7, 'Pixels')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes2_7.XLim = [0 255];
            app.UIAxes2_7.XTick = [0 128 255];
            app.UIAxes2_7.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_8 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_8, 'Green')
            xlabel(app.UIAxes2_8, 'Intensity')
            ylabel(app.UIAxes2_8, 'Pixels')
            zlabel(app.UIAxes2_8, 'Z')
            app.UIAxes2_8.XLim = [0 255];
            app.UIAxes2_8.XTick = [0 0.5 1];
            app.UIAxes2_8.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes_4 = uiaxes(app.HistogramEqualizationTab);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes2_9 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_9, 'Blue')
            xlabel(app.UIAxes2_9, 'Intensity')
            ylabel(app.UIAxes2_9, 'Pixels')
            zlabel(app.UIAxes2_9, 'Z')
            app.UIAxes2_9.XLim = [0 255];
            app.UIAxes2_9.XTick = [0 0.5 1];
            app.UIAxes2_9.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_10 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_10, 'Red')
            xlabel(app.UIAxes2_10, 'Intensity')
            ylabel(app.UIAxes2_10, 'Pixels')
            zlabel(app.UIAxes2_10, 'Z')
            app.UIAxes2_10.XLim = [0 255];
            app.UIAxes2_10.XTick = [0 128 255];
            app.UIAxes2_10.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_11 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_11, 'Green')
            xlabel(app.UIAxes2_11, 'Intensity')
            ylabel(app.UIAxes2_11, 'Pixels')
            zlabel(app.UIAxes2_11, 'Z')
            app.UIAxes2_11.XLim = [0 255];
            app.UIAxes2_11.XTick = [0 0.5 1];
            app.UIAxes2_11.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_12 = uiaxes(app.HistogramEqualizationTab);
            title(app.UIAxes2_12, 'Blue')
            xlabel(app.UIAxes2_12, 'Intensity')
            ylabel(app.UIAxes2_12, 'Pixels')
            zlabel(app.UIAxes2_12, 'Z')
            app.UIAxes2_12.XLim = [0 255];
            app.UIAxes2_12.XTick = [0 0.5 1];
            app.UIAxes2_12.XTickLabel = {'0'; '128'; '255'};
            app.InputImageLabel_2 = uilabel(app.HistogramEqualizationTab);
            app.InputImageLabel_2.Text = 'Input Image';
            app.OutputImageLabel_2 = uilabel(app.HistogramEqualizationTab);
            app.OutputImageLabel_2.Text = 'Output Image';
            app.SelectImageDropDown_2Label = uilabel(app.HistogramEqualizationTab);
            app.SelectImageDropDown_2Label.Text = 'Select Image';
            app.SelectImageDropDown_2 = uidropdown(app.HistogramEqualizationTab);
            app.HistogramSpecificationTab = uitab(app.TabGroup);
            app.HistogramSpecificationTab.Title = 'Histogram Specification';
            app.UIAxes2_13 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_13, 'Red')
            xlabel(app.UIAxes2_13, 'Intensity')
            ylabel(app.UIAxes2_13, 'Pixels')
            zlabel(app.UIAxes2_13, 'Z')
            app.UIAxes2_13.XTick = [0 128 255];
            app.UIAxes2_13.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_14 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_14, 'Green')
            xlabel(app.UIAxes2_14, 'Intensity')
            ylabel(app.UIAxes2_14, 'Pixels')
            zlabel(app.UIAxes2_14, 'Z')
            app.UIAxes2_14.XTick = [0 0.5 1];
            app.UIAxes2_14.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes_5 = uiaxes(app.HistogramSpecificationTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.HistogramSpecificationTab);
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes2_15 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_15, 'Blue')
            xlabel(app.UIAxes2_15, 'Intensity')
            ylabel(app.UIAxes2_15, 'Pixels')
            zlabel(app.UIAxes2_15, 'Z')
            app.UIAxes2_15.XTick = [0 0.5 1];
            app.UIAxes2_15.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_16 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_16, 'Red')
            xlabel(app.UIAxes2_16, 'Intensity')
            ylabel(app.UIAxes2_16, 'Pixels')
            zlabel(app.UIAxes2_16, 'Z')
            app.UIAxes2_16.XTick = [0 128 255];
            app.UIAxes2_16.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_17 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_17, 'Green')
            xlabel(app.UIAxes2_17, 'Intensity')
            ylabel(app.UIAxes2_17, 'Pixels')
            zlabel(app.UIAxes2_17, 'Z')
            app.UIAxes2_17.XTick = [0 0.5 1];
            app.UIAxes2_17.XTickLabel = {'0'; '128'; '255'};
            app.UIAxes2_18 = uiaxes(app.HistogramSpecificationTab);
            title(app.UIAxes2_18, 'Blue')
            xlabel(app.UIAxes2_18, 'Intensity')
            ylabel(app.UIAxes2_18, 'Pixels')
            zlabel(app.UIAxes2_18, 'Z')
            app.UIAxes2_18.XTick = [0 0.5 1];
            app.UIAxes2_18.XTickLabel = {'0'; '128'; '255'};
            app.InputImageLabel_3 = uilabel(app.HistogramSpecificationTab);
            app.InputImageLabel_3.Text = 'Input Image';
            app.OutputImageLabel_3 = uilabel(app.HistogramSpecificationTab);
            app.OutputImageLabel_3.Text = 'Output Image';
            app.SelectImageDropDown_3Label = uilabel(app.HistogramSpecificationTab);
            app.SelectImageDropDown_3Label.Text = 'Select Image';
            app.SelectImageDropDown_3 = uidropdown(app.HistogramSpecificationTab);
            app.TargetImageDropDown_2Label = uilabel(app.HistogramSpecificationTab);
            app.TargetImageDropDown_2Label.Text = 'Target Image';
            app.TargetImageDropDown_2 = uidropdown(app.HistogramSpecificationTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end